classdef RobotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes_3  matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        masterRobot = struct('robot', [], 'plotter', []);
        slaveRobot  = struct('robot', [], 'plotter', []);
    end
    
    methods (Access = public)
       
        function um = master_controller(app, qm, dqm)
            M = app.masterRobot.robot.get_mass_matrix(qm);
            C = app.masterRobot.robot.get_coriolis(qm, dqm);
            G = app.masterRobot.robot.get_graviry(qm);

            qm_des   = [0; 0];
            dqm_des  = [0; 0];
            ddqm_des = [0; 0];

            e  = qm_des  - qm;
            de = dqm_des - dqm;

            k1 = eye(2);
            k2 = eye(2);

            v = ddqm_des + k1 * de + k2 * e;
            um = M * v + C * dqm + G;
        end

        function slave_controller()
        end

        function right_fig_line_hand = draw_robots(app, qm, qs)
            right_fig = app.UIAxes;
            right_fig_line_hand = app.masterRobot.plotter.get_line_hand(right_fig, qm);

            left_fig = app.UIAxes_3;
            left_fig_line_hand = app.slaveRobot.plotter.get_line_hand(left_fig, qs);

            line_handle = {right_fig_line_hand, left_fig_line_hand};
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, masterRobot, slaveRobot)
            app.masterRobot.robot = masterRobot.robot;
            app.masterRobot.plotter = masterRobot.plotter;
            app.UIAxes.XLim = [-2, 2];
            app.UIAxes.YLim = [-2, 2];

            app.slaveRobot.robot = slaveRobot.robot;
            app.slaveRobot.plotter = slaveRobot.plotter;
            app.UIAxes_3.XLim = [-2, 2];
            app.UIAxes_3.YLim = [-2, 2];
            
        end

        % Button down function: UIAxes, UIAxes_3
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Master Robot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Slave Robot')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end